classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        Tab_Nghiem                    matlab.ui.container.Tab
        Nghiem_PhuongPhap             matlab.ui.control.DropDown
        PhngPhpDropDownLabel          matlab.ui.control.Label
        Nghiem_gxFunc                 matlab.ui.control.EditField
        gxEditFieldLabel              matlab.ui.control.Label
        Nghiem_slLap                  matlab.ui.control.EditField
        slnlpEditFieldLabel           matlab.ui.control.Label
        Nghiem_KQua                   matlab.ui.control.EditField
        ktquEditFieldLabel            matlab.ui.control.Label
        Nghiem_epsilon                matlab.ui.control.EditField
        epsilonLabel                  matlab.ui.control.Label
        Nghiem_B                      matlab.ui.control.EditField
        bEditFieldLabel               matlab.ui.control.Label
        Nghiem_A                      matlab.ui.control.EditField
        aEditFieldLabel               matlab.ui.control.Label
        Nghiem_Func                   matlab.ui.control.EditField
        fxEditFieldLabel              matlab.ui.control.Label
        Nghiem_ThcthiButton           matlab.ui.control.Button
        UIAxesNghiem                  matlab.ui.control.UIAxes
        Tab_NoiSuy                    matlab.ui.container.Tab
        NoiSuy_x                      matlab.ui.control.EditField
        NhpxLabel                     matlab.ui.control.Label
        NoiSuy_ya                     matlab.ui.control.EditField
        NhpmngyaLabel                 matlab.ui.control.Label
        NoiSuy_xa                     matlab.ui.control.EditField
        NhpmngxaLabel                 matlab.ui.control.Label
        NoiSuy_ChangeSwitch           matlab.ui.control.Switch
        ChnchSwitchLabel              matlab.ui.control.Label
        BieuThucNoiSuy                matlab.ui.control.Label
        GiaTriNoiSuy                  matlab.ui.control.Label
        NoiSuy_ThucThi                matlab.ui.control.Button
        UIAxesNoiSuy                  matlab.ui.control.UIAxes
        Tab_HoiQuy                    matlab.ui.container.Tab
        NhapGiaTriCanDuDoanEditField  matlab.ui.control.EditField
        NhpgitrcndonEditFieldLabel    matlab.ui.control.Label
        ChonPhuongPhapHoiQuyDropDown  matlab.ui.control.DropDown
        ChnPhngPhpHiQuyDropDownLabel  matlab.ui.control.Label
        HoiQuy_NhapDuLieuYEditField   matlab.ui.control.EditField
        NhpdliuyEditFieldLabel        matlab.ui.control.Label
        HoiQuy_NhapDuLieuXEditField   matlab.ui.control.EditField
        NhpdliuxEditFieldLabel        matlab.ui.control.Label
        HoiQuy_TINHButton             matlab.ui.control.Button
        HoiQuy_KetQuaDuDoanLabel      matlab.ui.control.Label
        HoiQuy_KetQuaPhuongTrinhHoiQuyLabel  matlab.ui.control.Label
        UIAxesHoiQuy                  matlab.ui.control.UIAxes
        Tab_DaoHam                    matlab.ui.container.Tab
        DaoHam_CachNhap               matlab.ui.container.ButtonGroup
        DaoHam_HamSo                  matlab.ui.control.RadioButton
        DaoHam_XY                     matlab.ui.control.RadioButton
        DaoHam_SaiSo                  matlab.ui.control.DropDown
        ChngitrsaisDropDownLabel      matlab.ui.control.Label
        DaoHam_ArrayY                 matlab.ui.control.EditField
        NhpmngyEditFieldLabel         matlab.ui.control.Label
        DaoHam_ArrayX                 matlab.ui.control.EditField
        NhpmngxEditFieldLabel         matlab.ui.control.Label
        DaoHam_PhuongPhap             matlab.ui.control.DropDown
        ChnphngphpohmDropDownLabel    matlab.ui.control.Label
        DaoHam_KetQua                 matlab.ui.control.Label
        DaoHamButton                  matlab.ui.control.Button
        DaoHam_GiaTriTinh             matlab.ui.control.NumericEditField
        imcntnhohmLabel               matlab.ui.control.Label
        DaoHam_BuocH                  matlab.ui.control.NumericEditField
        NhpbchEditFieldLabel          matlab.ui.control.Label
        DaoHam_NhapHamSo              matlab.ui.control.EditField
        NhphmsEditFieldLabel          matlab.ui.control.Label
        Tab_TichPhan                  matlab.ui.container.Tab
        TichPhan_CachNhapDuLieu       matlab.ui.control.DropDown
        CchNhpDropDownLabel           matlab.ui.control.Label
        TichPhan_NhapN                matlab.ui.control.NumericEditField
        NhpnLabel                     matlab.ui.control.Label
        CnbEditFieldLabel             matlab.ui.control.Label
        TichPhan_CanB                 matlab.ui.control.EditField
        TichPhan_CanA                 matlab.ui.control.EditField
        CnaEditFieldLabel             matlab.ui.control.Label
        TichPhan_Lamp                 matlab.ui.control.Lamp
        PhthinliLampLabel             matlab.ui.control.Label
        TichPhan_NhapY                matlab.ui.control.EditField
        MngYLabel                     matlab.ui.control.Label
        TichPhan_NhapX                matlab.ui.control.EditField
        MngXLabel                     matlab.ui.control.Label
        TichPhan_NhapHamSo            matlab.ui.control.EditField
        NhphmsEditFieldLabel_2        matlab.ui.control.Label
        TichPhan_KetQua               matlab.ui.control.Label
        TichPhan_Tinh                 matlab.ui.control.Button
        TichPhan_PhuongPhapTP         matlab.ui.container.ButtonGroup
        Simpson3phan8                 matlab.ui.control.RadioButton
        Simpson1phan3                 matlab.ui.control.RadioButton
        HinhThang                     matlab.ui.control.RadioButton
        TichPhan_Hoac                 matlab.ui.control.Label
        GiiThiuNhmTab                 matlab.ui.container.Tab
    end

    
    properties (Access = private)
        InterpolationMode = 'Lagrange';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Nghiem_ThcthiButton
        function Nghiem_ThcthiButtonPushed(app, event)
            maxIter = 20; %Số lần lặp tối đa
            
            % Kiểm tra xem có ô nào rỗng không
            if isempty(app.Nghiem_PhuongPhap.Value)
                uialert(app.UIFigure, 'Chưa chọn phương pháp', 'Lỗi');
                return;
            end
            
            % Kiểm tra ô g(x) va f(x) có rỗng hoac không nhap dung dinh
            % dang hay khong?
            if strcmp(app.Nghiem_PhuongPhap.Value, 'Lặp')
                try
                    g = str2func(['@(x)', app.Nghiem_gxFunc.Value]);

                catch
                    uialert(app.UIFigure, 'Nhập sai định dạng (function g(x)) hoặc đang rỗng!', 'Lỗi');
                    return;
                end
            else
                try
                    f =  str2func(['@(x)', app.Nghiem_Func.Value]);

                catch
                    uialert(app.UIFigure, 'Nhập sai định dạng (function f(x)) hoặc đang rỗng!', 'Lỗi');
                    return;
                end
            end

            % Gán Các Value Từ Text Field Vào Các Variable
            %Kiem tra co nhap dung dinh dang f(x) khong

            % Kiem tra co nhap dung dinh dang a va b hoặc đang rỗng không
            try
                a = str2double(app.Nghiem_A.Value);
                if isnan(a)
                    goto catch;
                end
            catch
                uialert(app.UIFigure, 'Nhập sai định dạng (a (integer)) hoặc đang rỗng!', 'Lỗi');
                return;
            end

            try
                b = str2double(app.Nghiem_B.Value);
                if isnan(b)
                    goto catch;
                end
            catch
                uialert(app.UIFigure, 'Nhập sai định dạng (b (integer)) hoặc đang rỗng!', 'Lỗi');
                return;
            end
            
            % Kiểm tra có nhập đúng định dạng epsilon hoặc đang rỗng không?
            try
                epsilon = str2double(app.Nghiem_epsilon.Value);
                if isnan(epsilon)
                    goto catch;
                end
            catch
                uialert(app.UIFigure, 'Nhập sai định dạng (epsilon (integer)) hoặc đang rỗng!', 'Lỗi');
                return;
            end

            % kiểm tra khoảng phân ly có hợp lệ hay không?
            if a >= b
                app.Nghiem_KQua.Value = 'NaN';
                app.Nghiem_slLap.Value = 'NaN';
                message = 'Khoảng phân ly không hợp lệ hoặc không tồn tại khoảng phân ly với các giá trị a và b';
                uialert(app.UIFigure, message, 'Lỗi');
                return;
            end

            % kiểm tra trong khoảng phân ly có nghiệm hay không?
            if strcmp(app.Nghiem_PhuongPhap.Value, 'Lặp')
%                 if sign(g(a)) == sign(g(b))
%                     app.Nghiem_KQua.Value = 'NaN';
%                     app.Nghiem_slLap.Value = 'NaN';
%                     message = 'Khoảng phân ly không chứa nghiệm hoặc chứa một số nghiệm chẵn. Vui lòng chọn khoảng phân ly khác.';
%                     uialert(app.UIFigure, message, 'Lỗi');
%                     return;
%                 end
            elseif sign(f(a)) == sign(f(b))
                app.Nghiem_KQua.Value = 'NaN';
                app.Nghiem_slLap.Value = 'NaN';
                message = 'Khoảng phân ly không chứa nghiệm hoặc chứa một số nghiệm chẵn. Vui lòng chọn khoảng phân ly khác.';
                uialert(app.UIFigure, message, 'Lỗi');
                return;
            end
    

            % Chọn hàm để function của phương pháp nào để giải phương trình
            switch app.Nghiem_PhuongPhap.Value
                case 'Chia đôi'
                    [root, iteration] = Nghiem_PhuongPhapChiaDoi(f, a, b, epsilon);

                case 'Lặp'
                    [root, iteration, message] = Nghiem_PhuongPhapLap(g, a, b, epsilon, maxIter);
                    if ~isempty(message)
                        uialert(app.UIFigure, message, 'Lỗi');
                        return;
                    end
                    

                case 'Newton'
                    [root, iteration] = Nghiem_PhuongPhapNewton(f, a, epsilon, maxIter);
            end

            app.Nghiem_KQua.Value = num2str(root);
            app.Nghiem_slLap.Value = num2str(iteration);

            % Vẽ đồ thị
            x = linspace(a/3, b*3, 1000);
            if strcmp(app.Nghiem_PhuongPhap.Value, 'Lặp')
                y = arrayfun(g, x);

            else
                y = arrayfun(f, x);
            end
            plot(app.UIAxesNghiem, x, y, 'b-', 'LineWidth', 1.5);
            grid(app.UIAxesNghiem, 'on');
        end

        % Value changed function: Nghiem_PhuongPhap
        function Nghiem_PhuongPhapValueChanged(app, event)
            selectedOption = app.Nghiem_PhuongPhap.Value;
        
            % Kiểm tra giá trị của dropdown và ẩn/hiển thị EditField dựa trên điều kiện
            if strcmp(selectedOption, 'Lặp') % Thay 'Option1' bằng giá trị của phần tử bạn muốn kiểm tra
                app.Nghiem_gxFunc.Visible = 'on'; % Ẩn EditField
                app.gxEditFieldLabel.Visible = 'on';

                app.Nghiem_Func.Enable = "off";

                uialert(app.UIFigure, "Người dùng phải tự tìm hàm lặp khi sử dụng phương pháp lặp!!!", "Lưu Ý");

                
            else
                app.Nghiem_gxFunc.Visible = 'off'; % Hiển thị EditField
                app.gxEditFieldLabel.Visible = 'off';

                app.Nghiem_Func.Enable = "on";
            end
            
        end

        % Button pushed function: NoiSuy_ThucThi
        function NoiSuy_ThucThiButtonPushed(app, event)
            xa = str2double(strsplit(app.NoiSuy_xa.Value));
            ya = str2double(strsplit(app.NoiSuy_ya.Value));
            x = str2double(app.NoiSuy_x.Value);

            if length(xa) ~= length(ya)
                uialert(app.UIFigure, 'Độ dài của xa và ya phải bằng nhau', 'Lỗi');
                return;
            end

            if strcmp(app.InterpolationMode, 'Lagrange')
                output = NoiSuy_Lagrange(xa, ya, x);
                daThuc = NoiSuy_DaThuc(xa, ya);
                string = ['Biểu thức nội suy là: ' daThuc];
                app.BieuThucNoiSuy.Text = string;
                daThuc = strrep(daThuc, '^', '.^');
                s = [0:0.1:10];
                f = str2func(['@(x)' daThuc]);
                plot(app.UIAxesNoiSuy, s, f(s), '--');
                title(app.UIAxesNoiSuy, 'Đồ thị nội suy Lagrange');
            elseif strcmp(app.InterpolationMode, 'Newton')
                output = NoiSuy_NewtonInterpolation(xa, ya, x);
                bieuThuc = NoiSuy_BieuThuc(xa, ya);
                string = ['Biểu thức nội suy là: ' bieuThuc];
                app.BieuThucNoiSuy.Text = string;
                bieuThuc = strrep(bieuThuc, '^', '.^');
                s = [0:0.1:10];
                f = str2func(['@(x)' bieuThuc]);
                plot(app.UIAxesNoiSuy, s, f(s), '--');
                title(app.UIAxesNoiSuy, 'Đồ thị nội suy Newton');
            end

            result = ['Giá trị nội suy là: ' num2str(output)];
            app.GiaTriNoiSuy.Text = result;
        end

        % Value changed function: NoiSuy_ChangeSwitch
        function NoiSuy_ChangeSwitchValueChanged(app, event)
            value = app.NoiSuy_ChangeSwitch.Value;
            if value
                app.InterpolationMode = 'Newton';
            else
                app.InterpolationMode = 'Lagrange';
            end
            
        end

        % Button pushed function: HoiQuy_TINHButton
        function HoiQuy_TINHButtonPushed(app, event)
            % Chuyển đổi giá trị nhập vào từ người dùng thành số
            x = str2num(app.HoiQuy_NhapDuLieuXEditField.Value);
            y = str2num(app.HoiQuy_NhapDuLieuYEditField.Value);
            x_pred = str2num(app.NhapGiaTriCanDuDoanEditField.Value);

            %Kiểm tra kích thước cửa ma trận x và y
            if length(x) ~= length(y)
                uialert(app.UIFigure, 'Kích thước của ma trận x và y phải bằng nhau!', "Lỗi!!!");
                return;
            end
            
            % Khởi tạo x_pos và yfit
            x_pos = x;
            yfit = zeros(size(x));
            
            % Lấy phương pháp hồi quy từ người dùng
            method = app.ChonPhuongPhapHoiQuyDropDown.Value;
            
            switch method
                case 'Tuyến tính'
                    % Hồi quy tuyến tính
                    [a1, a0, r2] = HoiQuy_Regress(x, y);
                    yfit = a0 + a1*x;
                    y_pred = a0 + a1*x_pred;
                    app.HoiQuy_KetQuaPhuongTrinhHoiQuyLabel.Text = sprintf('y = %.2f + %.2f*x, \t R^2 = %.2f', a0, a1, r2);
                    app.HoiQuy_TINHButton.BackgroundColor = [255 0 0]/255;
                
                case 'Hàm mũ'
                    % Hồi quy hàm mũ
                    X = [ones(length(x),1) x'];
                    B = X\log(y)';
                    a = exp(B(1));
                    b = B(2);
                    yfit = a*exp(b*x);
                    y_pred = a*exp(b*x_pred);
                    app.HoiQuy_KetQuaPhuongTrinhHoiQuyLabel.Text = sprintf('y = %.2f*exp(%.2f*x)', a, b);
                    app.HoiQuy_TINHButton.BackgroundColor = [0 255 0]/255;
                
                case 'Logarit'
                    % Hồi quy logarit

                    % Lọc ra các giá trị x dương
                    idx = x > 0;
                    x_pos = x(idx);
                    y_pos = y(idx);

                    % Nối các mảng cột
                    X = [ones(length(x_pos),1) log(x_pos)'];

                    % Tính B
                    B = X\y_pos';
                    a = B(1);
                    b = B(2);
                    yfit = a + b*log(x_pos);
                    y_pred = a + b*log(x_pred);
                    app.HoiQuy_KetQuaPhuongTrinhHoiQuyLabel.Text = sprintf('y = %.2f + %.2f*log(x)', a, b);
                    app.HoiQuy_TINHButton.BackgroundColor = [0 0 255]/255;
            end
            
            % Cập nhật Label với giá trị dự đoán
            app.HoiQuy_KetQuaDuDoanLabel.Text = sprintf('Giá trị dự đoán cho %.2f là %.2f', x_pred, y_pred);
            
            % Vẽ hàm hồi quy và dữ liệu lên đồ thị
            plot(app.UIAxesHoiQuy, x, y, 'ko', x_pos, yfit, 'b-');
            hold(app.UIAxesHoiQuy, 'on');
            plot(app.UIAxesHoiQuy, x_pred, y_pred, 'ro');
            hold(app.UIAxesHoiQuy, 'off');
            legend(app.UIAxesHoiQuy, 'Dữ liệu', sprintf('%s fit', method), 'Dự đoán', 'Location', 'best');
        end

        % Button pushed function: DaoHamButton
        function DaoHamButtonPushed(app, event)
            pp = app.DaoHam_PhuongPhap.Value;
            ss = app.DaoHam_SaiSo.Value;
            mangX = str2num(app.DaoHam_ArrayX.Value);
            mangY = str2num(app.DaoHam_ArrayY.Value);
            x = app.DaoHam_GiaTriTinh.Value;
            fx = app.DaoHam_NhapHamSo.Value;
            h = app.DaoHam_BuocH.Value;
            if (app.DaoHam_HamSo.Value == 1)
                if (ss == "O(h^2)")
                    [dx] = DaoHam_TinhDaoHamTuHamSoH2(fx,x,h,pp);
                else
                    [dx] = DaoHam_TinhDaoHamTuHamSoH(fx,x,h,pp);
                end 
            else
                if (ss == "O(h^2)")
                    [dx, message] = DaoHam_TinhDaoHamTuMangH2(mangX,mangY,x,h,pp);
                    if ~isempty(message)
                        uialert(app.UIFigure, message, 'Lỗi');
                    end
                else
                    [dx, message] = DaoHam_TinhDaoHamTuMangH(mangX,mangY,x,h,pp);
                    if ~isempty(message)
                        uialert(app.UIFigure, message, 'Lỗi');
                    end
                end 
            end
            dx = double(dx)
            if (ss == "O(h)")
                if (pp == "Xấp xỉ trung tâm")
                app.DaoHam_KetQua.Text = "Không thực hiện được";
                else
                app.DaoHam_KetQua.Text = num2str(dx);
                end
            else
                app.DaoHam_KetQua.Text = num2str(dx);
            end
        end

        % Value changed function: DaoHam_SaiSo
        function DaoHam_SaiSoValueChanged(app, event)
            selectedButton = app.DaoHam_CachNhap.SelectedObject;
            if (app.DaoHam_HamSo.Value == 1)
                app.DaoHam_ArrayX.Visible = "off";
                app.DaoHam_ArrayY.Visible = "off";
                app.DaoHam_NhapHamSo.Visible = "on";
            else
                app.DaoHam_ArrayX.Visible = "on";
                app.DaoHam_ArrayY.Visible = "on";
                app.DaoHam_NhapHamSo.Visible = "off";
            end
            
        end

        % Button pushed function: TichPhan_Tinh
        function TichPhan_TinhPushed(app, event)
            hamSo = app.TichPhan_NhapHamSo.Value;
            canA = str2num(app.TichPhan_CanA.Value);
            canB = str2num(app.TichPhan_CanB.Value);
            selectedOption = app.TichPhan_CachNhapDuLieu.Value;
            X = str2num(app.TichPhan_NhapX.Value);
            Y = str2num(app.TichPhan_NhapY.Value);
            N = app.TichPhan_NhapN.Value;
            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if strcmp(selectedOption, 'Hàm số')
                fx = app.TichPhan_NhapHamSo.Value;
                flag = 1; %bật cờ
            elseif strcmp(selectedOption, 'Mảng dữ liệu')
                fx = TichPhan_Lagrange(X, Y);
                fx = char(fx);
                flag = 1; %bật cờ
            end

            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.TichPhan_Lamp.Color = 'red';
            if flag == 1 
                    app.TichPhan_Lamp.Color = 'green';
                    
                    if(app.Simpson3phan8.Value)
                        ketqua = double(TichPhan_Simpson3phan8(fx,canA,canB,N));
                        app.TichPhan_KetQua.Text = num2str(ketqua);
                    elseif(app.HinhThang.Value)
                        ketqua = double(TichPhan_HinhThang(fx,canA,canB,N));
                        app.TichPhan_KetQua.Text = num2str(ketqua);
                    elseif(app.Simpson1phan3.Value)
                        ketqua = double(TichPhan_Simpson1phan3(fx,canA,canB,N));
                        app.TichPhan_KetQua.Text = num2str(ketqua);
                    end
            end
            
            clear fx;
            
        end

        % Value changed function: TichPhan_CachNhapDuLieu
        function TichPhan_CachNhapDuLieuValueChanged(app, event)
            selectedOption = app.TichPhan_CachNhapDuLieu.Value;

            % Kiểm tra giá trị của dropdown và ẩn/hiển thị EditField dựa trên điều kiện
            if strcmp(selectedOption, 'Mảng dữ liệu') % Thay 'Option1' bằng giá trị của phần tử bạn muốn kiểm tra
                app.TichPhan_NhapX.Enable = 'on'; % Ẩn EditField
                app.TichPhan_NhapY.Enable = 'on';

                app.TichPhan_NhapHamSo.Enable = "off";
                
            else
                app.TichPhan_NhapX.Enable = 'off'; % Hiển thị EditField
                app.TichPhan_NhapY.Enable = 'off';
                
                app.TichPhan_NhapHamSo.Enable = "on";
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_Nghiem = uitab(app.TabGroup);
            app.Tab_Nghiem.Title = 'Nghiệm';
            app.UIAxesNghiem = uiaxes(app.Tab_Nghiem);
            title(app.UIAxesNghiem, 'Đồ thị')
            xlabel(app.UIAxesNghiem, 'X')
            ylabel(app.UIAxesNghiem, 'Y')
            zlabel(app.UIAxesNghiem, 'Z')
            app.Nghiem_ThcthiButton = uibutton(app.Tab_Nghiem, 'push');
            app.Nghiem_ThcthiButton.ButtonPushedFcn = createCallbackFcn(app, @Nghiem_ThcthiButtonPushed, true);
            app.Nghiem_ThcthiButton.Text = 'Thực thi';
            app.fxEditFieldLabel = uilabel(app.Tab_Nghiem);
            app.fxEditFieldLabel.Text = 'f(x)';
            app.Nghiem_Func = uieditfield(app.Tab_Nghiem, 'text');
            app.Nghiem_Func.Value = 'x^3 - x - 1';
            app.aEditFieldLabel = uilabel(app.Tab_Nghiem);
            app.aEditFieldLabel.Text = 'a: ';
            app.Nghiem_A = uieditfield(app.Tab_Nghiem, 'text');
            app.Nghiem_A.Value = '1';
            app.bEditFieldLabel = uilabel(app.Tab_Nghiem);
            app.bEditFieldLabel.Text = 'b: ';
            app.Nghiem_B = uieditfield(app.Tab_Nghiem, 'text');
            app.Nghiem_B.Value = '2';
            app.epsilonLabel = uilabel(app.Tab_Nghiem);
            app.epsilonLabel.Text = 'epsilon';
            app.Nghiem_epsilon = uieditfield(app.Tab_Nghiem, 'text');
            app.Nghiem_epsilon.Value = '0.01';
            app.ktquEditFieldLabel = uilabel(app.Tab_Nghiem);
            app.ktquEditFieldLabel.Text = 'kết quả';
            app.Nghiem_KQua = uieditfield(app.Tab_Nghiem, 'text');
            app.Nghiem_KQua.Editable = 'off';
            app.slnlpEditFieldLabel = uilabel(app.Tab_Nghiem);
            app.slnlpEditFieldLabel.Text = 'số lần lặp';
            app.Nghiem_slLap = uieditfield(app.Tab_Nghiem, 'text');
            app.Nghiem_slLap.Editable = 'off';
            app.gxEditFieldLabel = uilabel(app.Tab_Nghiem);
            app.gxEditFieldLabel.Visible = 'off';
            app.gxEditFieldLabel.Text = 'g(x)';
            app.Nghiem_gxFunc = uieditfield(app.Tab_Nghiem, 'text');
            app.Nghiem_gxFunc.Visible = 'off';
            app.Nghiem_gxFunc.Value = '(x + 1)^(1/3)';
            app.PhngPhpDropDownLabel = uilabel(app.Tab_Nghiem);
            app.PhngPhpDropDownLabel.Text = 'Phương Pháp';
            app.Nghiem_PhuongPhap = uidropdown(app.Tab_Nghiem);
            app.Nghiem_PhuongPhap.Items = {'', 'Chia đôi', 'Lặp', 'Newton'};
            app.Nghiem_PhuongPhap.ValueChangedFcn = createCallbackFcn(app, @Nghiem_PhuongPhapValueChanged, true);
            app.Nghiem_PhuongPhap.Value = '';
            app.Tab_NoiSuy = uitab(app.TabGroup);
            app.Tab_NoiSuy.Title = 'Nội Suy';
            app.UIAxesNoiSuy = uiaxes(app.Tab_NoiSuy);
            title(app.UIAxesNoiSuy, 'Đồ thị nội suy')
            xlabel(app.UIAxesNoiSuy, 's = [0:0.1:10]')
            ylabel(app.UIAxesNoiSuy, 'f(s)')
            zlabel(app.UIAxesNoiSuy, 'Z')
            app.NoiSuy_ThucThi = uibutton(app.Tab_NoiSuy, 'push');
            app.NoiSuy_ThucThi.ButtonPushedFcn = createCallbackFcn(app, @NoiSuy_ThucThiButtonPushed, true);
            app.NoiSuy_ThucThi.Text = 'Thực thi';
            app.GiaTriNoiSuy = uilabel(app.Tab_NoiSuy);
            app.GiaTriNoiSuy.Text = 'Giá trị nội suy là: ';
            app.BieuThucNoiSuy = uilabel(app.Tab_NoiSuy);
            app.BieuThucNoiSuy.Text = 'Biểu thức nội suy là: ';
            app.ChnchSwitchLabel = uilabel(app.Tab_NoiSuy);
            app.ChnchSwitchLabel.Text = 'Chọn chế độ';
            app.NoiSuy_ChangeSwitch = uiswitch(app.Tab_NoiSuy, 'slider');
            app.NoiSuy_ChangeSwitch.Items = {'Lagrange', 'Newton'};
            app.NoiSuy_ChangeSwitch.ValueChangedFcn = createCallbackFcn(app, @NoiSuy_ChangeSwitchValueChanged, true);
            app.NoiSuy_ChangeSwitch.Value = 'Lagrange';
            app.NhpmngxaLabel = uilabel(app.Tab_NoiSuy);
            app.NhpmngxaLabel.Text = 'Nhập mảng xa';
            app.NoiSuy_xa = uieditfield(app.Tab_NoiSuy, 'text');
            app.NoiSuy_xa.Value = '0, 10, 15, 20, 22.5, 30';
            app.NhpmngyaLabel = uilabel(app.Tab_NoiSuy);
            app.NhpmngyaLabel.Text = 'Nhập mảng ya';
            app.NoiSuy_ya = uieditfield(app.Tab_NoiSuy, 'text');
            app.NoiSuy_ya.Value = '0, 227.04, 362.78, 517.35, 602.97, 901.67';
            app.NhpxLabel = uilabel(app.Tab_NoiSuy);
            app.NhpxLabel.Text = 'Nhập x';
            app.NoiSuy_x = uieditfield(app.Tab_NoiSuy, 'text');
            app.NoiSuy_x.Value = '16';
            app.Tab_HoiQuy = uitab(app.TabGroup);
            app.Tab_HoiQuy.Title = 'Hồi Quy';
            app.UIAxesHoiQuy = uiaxes(app.Tab_HoiQuy);
            title(app.UIAxesHoiQuy, 'Đồ thị hàm hồi quy và dữ liệu thực')
            xlabel(app.UIAxesHoiQuy, 'X')
            ylabel(app.UIAxesHoiQuy, 'Y')
            zlabel(app.UIAxesHoiQuy, 'Z')
            app.HoiQuy_KetQuaPhuongTrinhHoiQuyLabel = uilabel(app.Tab_HoiQuy);
            app.HoiQuy_KetQuaPhuongTrinhHoiQuyLabel.Text = 'Kết quả phương trình hồi quy';
            app.HoiQuy_KetQuaDuDoanLabel = uilabel(app.Tab_HoiQuy);
            app.HoiQuy_KetQuaDuDoanLabel.Text = 'Kết quả dự đoán';
            app.HoiQuy_TINHButton = uibutton(app.Tab_HoiQuy, 'push');
            app.HoiQuy_TINHButton.ButtonPushedFcn = createCallbackFcn(app, @HoiQuy_TINHButtonPushed, true);
            app.HoiQuy_TINHButton.Text = 'TÍNH';
            app.NhpdliuxEditFieldLabel = uilabel(app.Tab_HoiQuy);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.HoiQuy_NhapDuLieuXEditField = uieditfield(app.Tab_HoiQuy, 'text');
            app.HoiQuy_NhapDuLieuXEditField.Value = '[30, 35, 37, 50, 55]';
            app.NhpdliuyEditFieldLabel = uilabel(app.Tab_HoiQuy);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.HoiQuy_NhapDuLieuYEditField = uieditfield(app.Tab_HoiQuy, 'text');
            app.HoiQuy_NhapDuLieuYEditField.Value = '[500, 550, 570, 700, 750]';
            app.ChnPhngPhpHiQuyDropDownLabel = uilabel(app.Tab_HoiQuy);
            app.ChnPhngPhpHiQuyDropDownLabel.Text = 'Chọn Phương Pháp Hồi Quy';
            app.ChonPhuongPhapHoiQuyDropDown = uidropdown(app.Tab_HoiQuy);
            app.ChonPhuongPhapHoiQuyDropDown.Items = {'Tuyến tính', 'Hàm mũ', 'Logarit'};
            app.ChonPhuongPhapHoiQuyDropDown.Value = 'Tuyến tính';
            app.NhpgitrcndonEditFieldLabel = uilabel(app.Tab_HoiQuy);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán';
            app.NhapGiaTriCanDuDoanEditField = uieditfield(app.Tab_HoiQuy, 'text');
            app.NhapGiaTriCanDuDoanEditField.Value = '40';
            app.Tab_DaoHam = uitab(app.TabGroup);
            app.Tab_DaoHam.Title = 'Đạo hàm';
            app.NhphmsEditFieldLabel = uilabel(app.Tab_DaoHam);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.DaoHam_NhapHamSo = uieditfield(app.Tab_DaoHam, 'text');
            app.DaoHam_NhapHamSo.Value = '- 0.1*x^4 - 0.15*x^3 - 0.5*x^2 - 0.25x + 1.2';
            app.NhpbchEditFieldLabel = uilabel(app.Tab_DaoHam);
            app.NhpbchEditFieldLabel.Text = 'Nhập bước h';
            app.DaoHam_BuocH = uieditfield(app.Tab_DaoHam, 'numeric');
            app.DaoHam_BuocH.Value = 0.25;
            app.imcntnhohmLabel = uilabel(app.Tab_DaoHam);
            app.imcntnhohmLabel.Text = 'Điểm cần tính đạo hàm ';
            app.DaoHam_GiaTriTinh = uieditfield(app.Tab_DaoHam, 'numeric');
            app.DaoHam_GiaTriTinh.Value = 0.5;
            app.DaoHamButton = uibutton(app.Tab_DaoHam, 'push');
            app.DaoHamButton.ButtonPushedFcn = createCallbackFcn(app, @DaoHamButtonPushed, true);
            app.DaoHamButton.Text = 'Đạo hàm';
            app.DaoHam_KetQua = uilabel(app.Tab_DaoHam);
            app.DaoHam_KetQua.Text = '                        Kết quả';
            app.ChnphngphpohmDropDownLabel = uilabel(app.Tab_DaoHam);
            app.ChnphngphpohmDropDownLabel.Text = 'Chọn phương pháp đạo hàm';
            app.DaoHam_PhuongPhap = uidropdown(app.Tab_DaoHam);
            app.DaoHam_PhuongPhap.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
            app.DaoHam_PhuongPhap.Value = 'Xấp xỉ tiến';
            app.NhpmngxEditFieldLabel = uilabel(app.Tab_DaoHam);
            app.NhpmngxEditFieldLabel.Text = 'Nhập mảng x';
            app.DaoHam_ArrayX = uieditfield(app.Tab_DaoHam, 'text');
            app.DaoHam_ArrayX.Value = '0, 0.25, 0.5, 0.75, 1';
            app.NhpmngyEditFieldLabel = uilabel(app.Tab_DaoHam);
            app.NhpmngyEditFieldLabel.Text = 'Nhập mảng y';
            app.DaoHam_ArrayY = uieditfield(app.Tab_DaoHam, 'text');
            app.DaoHam_ArrayY.Value = '1.2, 1.103, 0.925, 0.636, 0.2';
            app.ChngitrsaisDropDownLabel = uilabel(app.Tab_DaoHam);
            app.ChngitrsaisDropDownLabel.Text = 'Chọn giá trị sai số';
            app.DaoHam_SaiSo = uidropdown(app.Tab_DaoHam);
            app.DaoHam_SaiSo.Items = {'O(h)', 'O(h^2)'};
            app.DaoHam_SaiSo.ValueChangedFcn = createCallbackFcn(app, @DaoHam_SaiSoValueChanged, true);
            app.DaoHam_SaiSo.Value = 'O(h)';
            app.DaoHam_CachNhap = uibuttongroup(app.Tab_DaoHam);
            app.DaoHam_CachNhap.Title = '    Chọn cách nhập';
            app.DaoHam_XY = uiradiobutton(app.DaoHam_CachNhap);
            app.DaoHam_XY.Text = 'Nhập mảng x,y';
            app.DaoHam_XY.Value = true;
            app.DaoHam_HamSo = uiradiobutton(app.DaoHam_CachNhap);
            app.DaoHam_HamSo.Text = 'Nhập hàm số';
            app.Tab_TichPhan = uitab(app.TabGroup);
            app.Tab_TichPhan.Title = 'Tích Phân';
            app.TichPhan_Hoac = uilabel(app.Tab_TichPhan);
            app.TichPhan_Hoac.Text = 'Hoặc';
            app.TichPhan_PhuongPhapTP = uibuttongroup(app.Tab_TichPhan);
            app.TichPhan_PhuongPhapTP.Title = 'Chọn phương pháp Tích phân';
            app.HinhThang = uiradiobutton(app.TichPhan_PhuongPhapTP);
            app.HinhThang.Text = 'Hình thang';
            app.HinhThang.Value = true;
            app.Simpson1phan3 = uiradiobutton(app.TichPhan_PhuongPhapTP);
            app.Simpson1phan3.Text = 'Simpson 1/3';
            app.Simpson3phan8 = uiradiobutton(app.TichPhan_PhuongPhapTP);
            app.Simpson3phan8.Text = 'Simpson 3/8';
            app.TichPhan_Tinh = uibutton(app.Tab_TichPhan, 'push');
            app.TichPhan_Tinh.ButtonPushedFcn = createCallbackFcn(app, @TichPhan_TinhPushed, true);
            app.TichPhan_Tinh.Text = 'Tính';
            app.TichPhan_KetQua = uilabel(app.Tab_TichPhan);
            app.TichPhan_KetQua.Text = 'Kết quả';
            app.NhphmsEditFieldLabel_2 = uilabel(app.Tab_TichPhan);
            app.NhphmsEditFieldLabel_2.Text = 'Nhập hàm số';
            app.TichPhan_NhapHamSo = uieditfield(app.Tab_TichPhan, 'text');
            app.TichPhan_NhapHamSo.Enable = 'off';
            app.TichPhan_NhapHamSo.Value = '1/(1+x^2)';
            app.MngXLabel = uilabel(app.Tab_TichPhan);
            app.MngXLabel.Text = 'Mảng x:';
            app.TichPhan_NhapX = uieditfield(app.Tab_TichPhan, 'text');
            app.TichPhan_NhapX.Value = '1, 2, 3, 4, 5';
            app.MngYLabel = uilabel(app.Tab_TichPhan);
            app.MngYLabel.Text = 'Mảng y(x):';
            app.TichPhan_NhapY = uieditfield(app.Tab_TichPhan, 'text');
            app.TichPhan_NhapY.Value = '1/2, 1/5, 1/10, 1/17, 1/26';
            app.PhthinliLampLabel = uilabel(app.Tab_TichPhan);
            app.PhthinliLampLabel.Text = 'Phát hiện lỗi';
            app.TichPhan_Lamp = uilamp(app.Tab_TichPhan);
            app.CnaEditFieldLabel = uilabel(app.Tab_TichPhan);
            app.CnaEditFieldLabel.Text = 'Cận a:';
            app.TichPhan_CanA = uieditfield(app.Tab_TichPhan, 'text');
            app.TichPhan_CanA.Value = '1';
            app.TichPhan_CanB = uieditfield(app.Tab_TichPhan, 'text');
            app.TichPhan_CanB.Value = '5';
            app.CnbEditFieldLabel = uilabel(app.Tab_TichPhan);
            app.CnbEditFieldLabel.Text = 'Cận b:';
            app.NhpnLabel = uilabel(app.Tab_TichPhan);
            app.NhpnLabel.Text = 'Nhập n (n đoạn):';
            app.TichPhan_NhapN = uieditfield(app.Tab_TichPhan, 'numeric');
            app.TichPhan_NhapN.Value = 4;
            app.CchNhpDropDownLabel = uilabel(app.Tab_TichPhan);
            app.CchNhpDropDownLabel.Text = 'Cách Nhập:';
            app.TichPhan_CachNhapDuLieu = uidropdown(app.Tab_TichPhan);
            app.TichPhan_CachNhapDuLieu.Items = {'Mảng dữ liệu', 'Hàm số'};
            app.TichPhan_CachNhapDuLieu.ValueChangedFcn = createCallbackFcn(app, @TichPhan_CachNhapDuLieuValueChanged, true);
            app.TichPhan_CachNhapDuLieu.Value = 'Mảng dữ liệu';
            app.GiiThiuNhmTab = uitab(app.TabGroup);
            app.GiiThiuNhmTab.Title = 'Giới Thiêu Nhóm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end